classdef spectrum_converter_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        LightConversionperAreaPanel  matlab.ui.container.Panel
        PFDmolsmEditField            matlab.ui.control.NumericEditField
        PFDmolsmEditFieldLabel       matlab.ui.control.Label
        PPFDmolsmEditField           matlab.ui.control.NumericEditField
        PPFDmolsmEditFieldLabel      matlab.ui.control.Label
        IlluminancelxEditField       matlab.ui.control.NumericEditField
        IlluminancelxEditFieldLabel  matlab.ui.control.Label
        InputDropDown_2              matlab.ui.control.DropDown
        VarentradaEditField_2        matlab.ui.control.NumericEditField
        LightConversionPanel         matlab.ui.container.Panel
        PFmolsEditField              matlab.ui.control.NumericEditField
        PFmolsEditFieldLabel         matlab.ui.control.Label
        PPFmolsEditField             matlab.ui.control.NumericEditField
        PPFmolsEditFieldLabel        matlab.ui.control.Label
        LuminousFluxlmEditField      matlab.ui.control.NumericEditField
        FluxoLuminosolmLabel         matlab.ui.control.Label
        RadiantPowerWEditField       matlab.ui.control.NumericEditField
        PotnciaRadianteWLabel        matlab.ui.control.Label
        InputEditField               matlab.ui.control.NumericEditField
        InputDropDown                matlab.ui.control.DropDown
        ChooseSpectrumFilePanel      matlab.ui.container.Panel
        LoadedSpectraDropDown        matlab.ui.control.DropDown
        OpenFileButton               matlab.ui.control.Button
        OpenFolderButton             matlab.ui.control.Button
        SaveImageButton              matlab.ui.control.Button
        Image                        matlab.ui.control.Image
        AboutButton                  matlab.ui.control.Button
        PhotometricConverterLabel    matlab.ui.control.Label
        CalculateConversionsButton   matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        FL_PF_g 
        FL_PPF_g 
        FL_Wrad_g 
        Wrad_PPF_g
        Wrad_PF_g
        PF_PPF_g
        CF_simulacao_distrib_PPFD_V6
        ax_aux
        WL_aux;
        SPD_aux;
        VWdelta_aux;
        LEF_aux;
        SelectedFolderPath
    end
    
    methods (Access = public)
        
        function openSpectrumFile(app, fullPath)
            % Load data from the selected file
        
            [~,~,ext] = fileparts(fullPath);
        
            if strcmp(ext, '.txt')
                fileID = fopen(fullPath, 'r');
                fgetl(fileID); % Ignore header
                temp = textscan(fileID, '%f%f%f', 'Delimiter', '\t');
                fclose(fileID);
                WL = temp{1}';
                SPD = temp{2}';
                Wrad = temp{3}' / 1000; % Convert mW to W
        
            elseif strcmp(ext, '.mat')
                data = load(fullPath);
                fields = fieldnames(data);
                SPD_mat = data.(fields{1});
                WL = SPD_mat(:,1)';
                SPD = SPD_mat(:,2)';
        
            elseif strcmp(ext, '.csv')
                data = readtable(fullPath, 'HeaderLines', 1);
                WL = data.Var1';
                SPD = data.Var2';
                Wrad = data.Var3';

             elseif strcmp(ext, '.CSV')
                data = readtable(fullPath, 'HeaderLines', 1);
                WL = data.Var1';
                SPD = data.Var2';
                Wrad = data.Var3'

            else
                uialert(app.UIFigure, 'Unsupported file format.', 'Error');
                return;
            end
        
            Wa = min(WL);
            Wb = max(WL);
        
            % Interpolate to 1 nm step
            WL_interp = Wa:1:Wb;
            SPD_interp = interp1(WL, SPD, WL_interp, 'linear', 0);
        
            % CIE 1978 photopic curve
            CIE78 = [0	0	0	0	0.000200000000000000	0.000395560000000000	0.000800000000000000	0.00154570000000000	0.00280000000000000	0.00465620000000000	0.00740000000000000	0.0117790000000000	0.0175000000000000	0.0226780000000000	0.0273000000000000	0.0325840000000000	0.0379000000000000	0.0423910000000000	0.0468000000000000	0.0521220000000000	0.0600000000000000	0.0729420000000000	0.0909800000000000	0.112840000000000	0.139020000000000	0.169870000000000	0.208020000000000	0.258080000000000	0.323000000000000	0.405400000000000	0.503000000000000	0.608110000000000	0.710000000000000	0.795100000000000	0.862000000000000	0.915050000000000	0.954000000000000	0.980040000000000	0.994950000000000	1	0.995000000000000	0.978750000000000	0.952000000000000	0.915580000000000	0.870000000000000	0.816230000000000	0.757000000000000	0.694830000000000	0.631000000000000	0.566540000000000	0.503000000000000	0.441720000000000	0.381000000000000	0.320520000000000	0.265000000000000	0.217020000000000	0.175000000000000	0.138120000000000	0.107000000000000	0.0816520000000000	0.0610000000000000	0.0443270000000000	0.0320000000000000	0.0234540000000000	0.0170000000000000	0.0118720000000000	0.00821000000000000	0.00577230000000000	0.00410200000000000	0.00292910000000000	0.00209100000000000	0.00148220000000000	0.00104700000000000	0.000740150000000000	0.000520000000000000	0.000360930000000000	0.000249200000000000	0.000172310000000000	0.000120000000000000	8.46200000000000e-05	6.00000000000000e-05	4.24460000000000e-05	3.00000000000000e-05	2.12100000000000e-05	1.49890000000000e-05	1.05840000000000e-05	7.46560000000000e-06	5.25920000000000e-06	3.70280000000000e-06	2.60760000000000e-06	1.83650000000000e-06	1.29500000000000e-06	9.10920000000000e-07	6.35640000000000e-07]';
            WL78 = 360:5:825;
        
            for i = 1:length(WL_interp)
                LEF(i) = interp1(WL78, CIE78, WL_interp(i), 'linear', 0);
            end
        
            % Generate PAR curve
            PAR_curve = double(WL_interp >= 400 & WL_interp <= 700);
        
            % Update plot
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, WL_interp, SPD_interp, 'b-', 'LineWidth', 2);
            plot(app.UIAxes, WL_interp, LEF, '--k', 'LineWidth', 1);
            plot(app.UIAxes, WL_interp, PAR_curve, '--', 'Color', [0 0.7 0], 'LineWidth', 1);
            ylim(app.UIAxes, [0 1.2]);
            xlim(app.UIAxes, [Wa Wb]);
            legend(app.UIAxes, 'Light SPD', 'Human Eye Photopic Curve', 'PAR Curve', 'Location', 'northeast');
            xlabel(app.UIAxes, 'Wavelength (nm)');
            ylabel(app.UIAxes, 'Relative Intensity / Arbitrary Units');
            title(app.UIAxes, 'Normalized Spectrum');
            grid(app.UIAxes, 'on');
            hold(app.UIAxes, 'off');
        
            % Photon Flux calculations
            ConstPP = (1e-3) / (6.02214076e23 * 2.998e8 * 6.62607015e-34);
        
            ind_inf = find(WL_interp >= 400, 1, 'first');
            ind_sup = find(WL_interp <= 700, 1, 'last');
        
            PF = sum(WL_interp .* SPD_interp) * ConstPP;
            PPF = sum(WL_interp(ind_inf:ind_sup) .* SPD_interp(ind_inf:ind_sup)) * ConstPP;
        
            FL = 683 * sum(SPD_interp .* LEF);
            RadiantPower = sum(SPD_interp);
        
            % Relations
            FL_PF = FL / PF;
            FL_PPF = FL / PPF;
            FL_Wrad = FL / RadiantPower;
            Wrad_PPF = RadiantPower / PPF;
            Wrad_PF = RadiantPower / PF;
            PF_PPF = PF / PPF;
        
            % Save variables into app properties
            app.FL_PF_g = FL_PF;
            app.FL_PPF_g = FL_PPF;
            app.FL_Wrad_g = FL_Wrad;
            app.Wrad_PF_g = Wrad_PF;
            app.Wrad_PPF_g = Wrad_PPF;
            app.PF_PPF_g = PF_PPF;
            app.WL_aux = WL_interp;
            app.SPD_aux = SPD_interp;
            app.LEF_aux = LEF;
            app.VWdelta_aux = PAR_curve;

            % Initialize luminous flux value
            FL_initial =  683 * sum(Wrad .* LEF);
            app.InputEditField.Value = FL_initial;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenFileButton
        function OpenFileButtonPushed(app, event)
            % Clear previous plot
            cla(app.UIAxes);
        
            % Ask user for a file
            [filename, path] = uigetfile({'*.txt;*.mat;*.csv'});
            if isequal(filename,0)
                return; % Cancel pressed
            end
        
            app.LoadedSpectraDropDown.Items = {filename};
            file = fullfile(path, filename);
        
            % Load data according to format
            openSpectrumFile(app, file)
            
        end

        % Value changed function: InputDropDown
        function InputDropDownValueChanged(app, event)
            
        end

        % Callback function
        function VarivelpticadeentradaDropDownOpening(app, event)
            
        end

        % Button pushed function: CalculateConversionsButton
        function CalculateConversionsButtonPushed(app, event)
            % Get selected inputs from dropdowns
            VarIn1 = app.InputDropDown.Value;
            VarIn2 = app.InputDropDown_2.Value;
            
            % Retrieve previously calculated conversion factors
            FL_PF = app.FL_PF_g;
            FL_PPF = app.FL_PPF_g;
            FL_Wrad = app.FL_Wrad_g;
            Wrad_PF = app.Wrad_PF_g;
            Wrad_PPF = app.Wrad_PPF_g;
            PF_PPF = app.PF_PPF_g;
        
            % Update based on first input selection (total quantities)
            if strcmp(VarIn1, 'Radiant Power [W]')
                app.RadiantPowerWEditField.Value = app.InputEditField.Value;
                app.LuminousFluxlmEditField.Value = FL_Wrad * app.InputEditField.Value;
                app.PPFmolsEditField.Value = (1 / Wrad_PPF) * app.InputEditField.Value;
                app.PFmolsEditField.Value = (1 / Wrad_PF) * app.InputEditField.Value;
        
            elseif strcmp(VarIn1, 'Luminous Flux [lm]')
                app.RadiantPowerWEditField.Value = (1 / FL_Wrad) * app.InputEditField.Value;
                app.LuminousFluxlmEditField.Value = app.InputEditField.Value;
                app.PPFmolsEditField.Value = (1 / FL_PPF) * app.InputEditField.Value;
                app.PFmolsEditField.Value = (1 / FL_PF) * app.InputEditField.Value;
        
            elseif strcmp(VarIn1, 'PPF [µmol/s]')
                app.RadiantPowerWEditField.Value = Wrad_PPF * app.InputEditField.Value;
                app.LuminousFluxlmEditField.Value = FL_PPF * app.InputEditField.Value;
                app.PPFmolsEditField.Value = app.InputEditField.Value;
                app.PFmolsEditField.Value = PF_PPF * app.InputEditField.Value;
        
            elseif strcmp(VarIn1, 'PF [µmol/s]')
                app.RadiantPowerWEditField.Value = Wrad_PF * app.InputEditField.Value;
                app.LuminousFluxlmEditField.Value = FL_PF * app.InputEditField.Value;
                app.PPFmolsEditField.Value = (1 / PF_PPF) * app.InputEditField.Value;
                app.PFmolsEditField.Value = app.InputEditField.Value;
            end   
        
            % Update based on second input selection (area-based quantities)
            if strcmp(VarIn2, 'Illuminance [lx]')
                app.IlluminancelxEditField.Value = app.VarentradaEditField_2.Value;
                app.PPFDmolsmEditField.Value = (1 / FL_PPF) * app.VarentradaEditField_2.Value;
                app.PFDmolsmEditField.Value = (1 / FL_PF) * app.VarentradaEditField_2.Value;
        
            elseif strcmp(VarIn2, 'PPFD [µmol/s/m²]')
                app.IlluminancelxEditField.Value = FL_PPF * app.VarentradaEditField_2.Value;
                app.PPFDmolsmEditField.Value = app.VarentradaEditField_2.Value;
                app.PFDmolsmEditField.Value = PF_PPF * app.VarentradaEditField_2.Value;
        
            elseif strcmp(VarIn2, 'PFD [µmol/s/m²]')
                app.IlluminancelxEditField.Value = FL_PF * app.VarentradaEditField_2.Value;
                app.PPFDmolsmEditField.Value = (1 / PF_PPF) * app.VarentradaEditField_2.Value;
                app.PFDmolsmEditField.Value = app.VarentradaEditField_2.Value;
            end
            
            
        end

        % Button pushed function: AboutButton
        function AboutButtonPushed(app, event)
            % Create a new help window
            helpFig = uifigure('Name', 'About', 'Position', [300, 300, 350, 220]);
            
            % Add label for the developer's name
            nameLabel = uilabel(helpFig, ...
                                'Text', 'Developed by Guilherme Ribeiro Silveira', ...
                                'Position', [20, 160, 310, 22], ...
                                'FontSize', 10);
            
            % Add label for the research group
            groupLabel = uilabel(helpFig, ...
                                 'Text', 'Research Group GEDRE - Lighting Intelligence', ...
                                 'Position', [20, 130, 310, 22], ...
                                 'FontSize', 10);
            
            % Add label for the email
            emailLabel = uilabel(helpFig, ...
                                 'Text', 'Contact: guilhermeribeiro201342@gmail.com', ...
                                 'Position', [20, 100, 310, 22], ...
                                 'FontSize', 10);
        
            % Add label for the GitHub
            githubLabel = uilabel(helpFig, ...
                                  'Text', 'GitHub: github.com/GuilhermeRS11', ...
                                  'Position', [20, 70, 310, 22], ...
                                  'FontSize', 10);
        
            % Add a close button
            closeButton = uibutton(helpFig, ...
                                   'Text', 'Close', ...
                                   'Position', [125, 20, 100, 30], ...
                                   'ButtonPushedFcn', @(btn,event) close(helpFig));
                       
        end

        % Callback function
        function SimulardistribuiodePPDFButtonPushed(app, event)
            
        end

        % Value changed function: RadiantPowerWEditField
        function RadiantPowerWEditFieldValueChanged(app, event)
            value = app.RadiantPowerWEditField.Value;
            
        end

        % Callback function
        function FileNameLoadedEditFieldValueChanged(app, event)
            value = app.FileNameLoadedEditField.Value;
           
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            % Ask user for the file to save
            [filename, path] = uiputfile({'*.png;*.jpg'});
            if isequal(filename,0)
                return; % Cancel pressed
            end
        
            % Retrieve stored spectrum data
            WL = app.WL_aux;
            SPD = app.SPD_aux;
            LEF = app.LEF_aux;
            PAR_curve = app.VWdelta_aux;
        
            % Create the plot
            figure;
            hold on;
            plot(WL, SPD, 'b-', 'LineWidth', 2);       % Light SPD
            plot(WL, LEF, '--k', 'LineWidth', 1);     % Human Eye Photopic Curve
            plot(WL, PAR_curve, '--', 'Color', [0 0.7 0], 'LineWidth', 1); % PAR Curve
            title('Spectral Power Distribution');
            xlabel('Wavelength [nm]');
            ylabel('Relative Intensity / Arbitrary Units');
            legend('Light SPD', 'Human Eye Photopic Curve', 'PAR Curve', 'Location', 'northeast');
            grid on;
            xlim([WL(1) WL(end)]);
            ylim([0 1.2]);
            hold off;
        
            % Export the figure
            ax = gca;
            exportgraphics(ax, fullfile(path, filename), 'Resolution', 300);
        
            % Close the figure automatically after saving
            close(gcf);
           
            
        end

        % Button pushed function: OpenFolderButton
        function OpenFolderButtonPushed(app, event)
            % Ask the user for a folder
            folderPath = uigetdir(pwd, 'Select a folder with spectrum files');
            
            % If the user canceled
            if isequal(folderPath, 0)
                return;
            end
            
            % Store the selected folder path
            app.SelectedFolderPath = folderPath;
            
            % List spectrum files
            spectraFiles = dir(fullfile(folderPath, '*.txt'));
            spectraFiles = [spectraFiles; dir(fullfile(folderPath, '*.csv'))];
            spectraFiles = [spectraFiles; dir(fullfile(folderPath, '*.mat'))];
        
            fileNames = {spectraFiles.name};
            if isempty(fileNames)
                fileNames = {'No valid files found'};
            end
            
            % Update dropdown
            app.LoadedSpectraDropDown.Items = fileNames;
            
            % Automatically select the first file if available
            if ~strcmp(fileNames{1}, 'No valid files found')
                app.LoadedSpectraDropDown.Value = fileNames{1};
            
                % OPTIONAL: Auto-load the first file immediately
                fullPath = fullfile(app.SelectedFolderPath, fileNames{1});
                openSpectrumFile(app, fullPath);
            end


        end

        % Value changed function: LoadedSpectraDropDown
        function LoadedSpectraDropDownValueChanged(app, event)
            % Clear previous plot
            cla(app.UIAxes);
            
            selectedFile = app.LoadedSpectraDropDown.Value;
    
            % Ignore if no valid file
            if strcmp(selectedFile, 'No valid files found') || isempty(app.SelectedFolderPath)
                return;
            end
        
            % Full path to selected file
            fullPath = fullfile(app.SelectedFolderPath, selectedFile);
        
            % Load the spectrum
            openSpectrumFile(app, fullPath);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Spectral Power Distribution')
            xlabel(app.UIAxes, 'Wavelength [nm]')
            ylabel(app.UIAxes, 'Normalized SPD')
            app.CalculateConversionsButton = uibutton(app.UIFigure, 'push');
            app.CalculateConversionsButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateConversionsButtonPushed, true);
            app.CalculateConversionsButton.Text = 'Calculate Conversions';
            app.PhotometricConverterLabel = uilabel(app.UIFigure);
            app.PhotometricConverterLabel.Text = 'Photometric Converter';
            app.AboutButton = uibutton(app.UIFigure, 'push');
            app.AboutButton.ButtonPushedFcn = createCallbackFcn(app, @AboutButtonPushed, true);
            app.AboutButton.Text = 'About';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logo_gedre.png';
            app.SaveImageButton = uibutton(app.UIFigure, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
            app.ChooseSpectrumFilePanel = uipanel(app.UIFigure);
            app.ChooseSpectrumFilePanel.Tooltip = {''};
            app.ChooseSpectrumFilePanel.Title = 'Choose Spectrum File';
            app.OpenFolderButton = uibutton(app.ChooseSpectrumFilePanel, 'push');
            app.OpenFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFolderButtonPushed, true);
            app.OpenFolderButton.Text = 'Open Folder';
            app.OpenFileButton = uibutton(app.ChooseSpectrumFilePanel, 'push');
            app.OpenFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFileButtonPushed, true);
            app.OpenFileButton.Text = 'Open File';
            app.LoadedSpectraDropDown = uidropdown(app.ChooseSpectrumFilePanel);
            app.LoadedSpectraDropDown.Items = {};
            app.LoadedSpectraDropDown.ValueChangedFcn = createCallbackFcn(app, @LoadedSpectraDropDownValueChanged, true);
            app.LoadedSpectraDropDown.Value = {};
            app.LightConversionPanel = uipanel(app.UIFigure);
            app.LightConversionPanel.Title = 'Light Conversion';
            app.InputDropDown = uidropdown(app.LightConversionPanel);
            app.InputDropDown.Items = {'Luminous Flux [lm]', 'Radiant Power [W]', 'PPF [µmol/s]', 'PF [µmol/s]'};
            app.InputDropDown.ValueChangedFcn = createCallbackFcn(app, @InputDropDownValueChanged, true);
            app.InputDropDown.Value = 'Luminous Flux [lm]';
            app.InputEditField = uieditfield(app.LightConversionPanel, 'numeric');
            app.InputEditField.ValueDisplayFormat = '%.4f';
            app.PotnciaRadianteWLabel = uilabel(app.LightConversionPanel);
            app.PotnciaRadianteWLabel.Text = 'Radiant Power [W]';
            app.RadiantPowerWEditField = uieditfield(app.LightConversionPanel, 'numeric');
            app.RadiantPowerWEditField.ValueDisplayFormat = '%.4f';
            app.RadiantPowerWEditField.ValueChangedFcn = createCallbackFcn(app, @RadiantPowerWEditFieldValueChanged, true);
            app.FluxoLuminosolmLabel = uilabel(app.LightConversionPanel);
            app.FluxoLuminosolmLabel.Text = 'Luminous Flux [lm]';
            app.LuminousFluxlmEditField = uieditfield(app.LightConversionPanel, 'numeric');
            app.LuminousFluxlmEditField.ValueDisplayFormat = '%.4f';
            app.PPFmolsEditFieldLabel = uilabel(app.LightConversionPanel);
            app.PPFmolsEditFieldLabel.Text = 'PPF [µmol/s]';
            app.PPFmolsEditField = uieditfield(app.LightConversionPanel, 'numeric');
            app.PPFmolsEditField.ValueDisplayFormat = '%.4f';
            app.PFmolsEditFieldLabel = uilabel(app.LightConversionPanel);
            app.PFmolsEditFieldLabel.Text = 'PF [µmol/s]';
            app.PFmolsEditField = uieditfield(app.LightConversionPanel, 'numeric');
            app.PFmolsEditField.ValueDisplayFormat = '%.4f';
            app.LightConversionperAreaPanel = uipanel(app.UIFigure);
            app.LightConversionperAreaPanel.Title = 'Light Conversion per Area';
            app.VarentradaEditField_2 = uieditfield(app.LightConversionperAreaPanel, 'numeric');
            app.VarentradaEditField_2.ValueDisplayFormat = '%.4f';
            app.InputDropDown_2 = uidropdown(app.LightConversionperAreaPanel);
            app.InputDropDown_2.Items = {'Iluminance [lx]', 'PPF [µmol/s]', 'PF [µmol/s]'};
            app.InputDropDown_2.Value = 'Iluminance [lx]';
            app.IlluminancelxEditFieldLabel = uilabel(app.LightConversionperAreaPanel);
            app.IlluminancelxEditFieldLabel.Text = 'Illuminance [lx]';
            app.IlluminancelxEditField = uieditfield(app.LightConversionperAreaPanel, 'numeric');
            app.IlluminancelxEditField.ValueDisplayFormat = '%.4f';
            app.PPFDmolsmEditFieldLabel = uilabel(app.LightConversionperAreaPanel);
            app.PPFDmolsmEditFieldLabel.Text = 'PPFD [µmol/s/m²]';
            app.PPFDmolsmEditField = uieditfield(app.LightConversionperAreaPanel, 'numeric');
            app.PPFDmolsmEditField.ValueDisplayFormat = '%.4f';
            app.PFDmolsmEditFieldLabel = uilabel(app.LightConversionperAreaPanel);
            app.PFDmolsmEditFieldLabel.Text = 'PFD [µmol/s/m²]';
            app.PFDmolsmEditField = uieditfield(app.LightConversionperAreaPanel, 'numeric');
            app.PFDmolsmEditField.ValueDisplayFormat = '%.4f';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = spectrum_converter_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end